classdef TestApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        GridLayout3              matlab.ui.container.GridLayout
        comR_tilde_SliderLabel   matlab.ui.control.Label
        comR_tilde_EditField     matlab.ui.control.NumericEditField
        comR_tilde_Spinner       matlab.ui.control.Spinner
        alpha_L_Slider           matlab.ui.control.Slider
        deltaEL_Slider           matlab.ui.control.Slider
        deltaR_Slider            matlab.ui.control.Slider
        k_SigX_Slider            matlab.ui.control.Slider
        deltaSigX_Slider         matlab.ui.control.Slider
        kappaX_Slider            matlab.ui.control.Slider
        alpha_sigX_Slider        matlab.ui.control.Slider
        k_comR_Slider            matlab.ui.control.Slider
        kappa_comR_Slider        matlab.ui.control.Slider
        kappaB_Slider            matlab.ui.control.Slider
        alpha_comR_Slider        matlab.ui.control.Slider
        delta_mtf_Slider         matlab.ui.control.Slider
        delta_tf_Slider          matlab.ui.control.Slider
        k_TF_f_Slider            matlab.ui.control.Slider
        k_TF_m_Slider            matlab.ui.control.Slider
        delta_XIP_Slider         matlab.ui.control.Slider
        qD_Slider                matlab.ui.control.Slider
        qD_EditField             matlab.ui.control.NumericEditField
        qD_Spinner               matlab.ui.control.Spinner
        Spinner2Label            matlab.ui.control.Label
        k_TF_m_EditField         matlab.ui.control.NumericEditField
        k_TF_m_Spinner           matlab.ui.control.Spinner
        Spinner2_2Label          matlab.ui.control.Label
        alpha_comR_EditField     matlab.ui.control.NumericEditField
        alpha_comR_Spinner       matlab.ui.control.Spinner
        Spinner2_4Label          matlab.ui.control.Label
        alpha_sigX_EditField     matlab.ui.control.NumericEditField
        alpha_sigX_Spinner       matlab.ui.control.Spinner
        Spinner2_5Label          matlab.ui.control.Label
        kappa_comR_EditField     matlab.ui.control.NumericEditField
        kappa_comR_Spinner       matlab.ui.control.Spinner
        kappa_comRLabel          matlab.ui.control.Label
        delta_tf_EditField       matlab.ui.control.NumericEditField
        delta_tf_Spinner         matlab.ui.control.Spinner
        Spinner2_3Label          matlab.ui.control.Label
        deltaSigX_EditField      matlab.ui.control.NumericEditField
        k_comRSliderLabel_4      matlab.ui.control.Label
        deltaSigX_Spinner        matlab.ui.control.Spinner
        deltaR_EditField         matlab.ui.control.NumericEditField
        k_comRSliderLabel_5      matlab.ui.control.Label
        deltaR_Spinner           matlab.ui.control.Spinner
        deltaEL_EditField        matlab.ui.control.NumericEditField
        k_comRSliderLabel_8      matlab.ui.control.Label
        deltaEL_Spinner          matlab.ui.control.Spinner
        k_comR_Spinner           matlab.ui.control.Spinner
        Spinner2_10Label         matlab.ui.control.Label
        kappaB_Spinner           matlab.ui.control.Spinner
        Spinner2_9Label          matlab.ui.control.Label
        delta_mtf_Spinner        matlab.ui.control.Spinner
        Spinner2_8Label          matlab.ui.control.Label
        k_TF_f_Spinner           matlab.ui.control.Spinner
        Spinner2_7Label          matlab.ui.control.Label
        delta_XIP_Spinner        matlab.ui.control.Spinner
        Spinner2_6Label          matlab.ui.control.Label
        alpha_L_EditField        matlab.ui.control.NumericEditField
        k_SigX_EditField         matlab.ui.control.NumericEditField
        kappaX_EditField         matlab.ui.control.NumericEditField
        k_comR_EditField         matlab.ui.control.NumericEditField
        kappaB_EditField         matlab.ui.control.NumericEditField
        delta_mtf_EditField      matlab.ui.control.NumericEditField
        k_TF_f_EditField         matlab.ui.control.NumericEditField
        delta_XIP_EditField      matlab.ui.control.NumericEditField
        k_comRSliderLabel_6      matlab.ui.control.Label
        alpha_L_Spinner          matlab.ui.control.Spinner
        k_comRSliderLabel_3      matlab.ui.control.Label
        k_SigX_Spinner           matlab.ui.control.Spinner
        k_comRSliderLabel_2      matlab.ui.control.Label
        kappaX_Spinner           matlab.ui.control.Spinner
        RightPanel               matlab.ui.container.Panel
        GridLayout2              matlab.ui.container.GridLayout
        XIP_inputnMSpinner       matlab.ui.control.Spinner
        XIP_inputnMSpinnerLabel  matlab.ui.control.Label
        SigXUIAxes               matlab.ui.control.UIAxes
        LuciferaseUIAxes         matlab.ui.control.UIAxes
        mRNAUIAxes               matlab.ui.control.UIAxes
        comR_XIPUIAxes           matlab.ui.control.UIAxes
        comRUIAxes               matlab.ui.control.UIAxes
        XIP_extUIAxes            matlab.ui.control.UIAxes
        XIP_intUIAxes            matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = private)


        function update_EditFields(app)
            % Update all EditField Values with value currently in
            % corresponding Spinners


            % List of all Prefixes 
            prfx_lst = ["qD", "k_TF_m", "delta_XIP", "k_TF_f", "alpha_comR", "kappaB", "k_comR", "alpha_sigX", "kappa_comR", "delta_tf", "delta_mtf",...
                "kappaX", "k_SigX", "deltaSigX", "alpha_L", "deltaR", "deltaEL", "comR_tilde"];


            for idx = 1:size(prfx_lst,2)
                prfx = prfx_lst(idx);
                val_cmdStr = "app."+prfx+"_Spinner.Value";
                val = eval(val_cmdStr);
                setFieldVal_cmdStr = "app."+prfx+"_EditField.Value="+num2str(val);
                eval(setFieldVal_cmdStr);

            end


        end
        
        function calcSysResponse(app)

            qD = app.qD_EditField.Value;
            k_TF_m = app.k_TF_m_EditField.Value;
            delta_XIP = app.delta_XIP_EditField.Value;
            k_TF_f = app.k_TF_f_EditField.Value;
            alpha_comR = app.alpha_comR_EditField.Value;
            kappaB = app.kappaB_EditField.Value;
            k_comR = app.k_comR_EditField.Value;
            alpha_sigX= app.alpha_sigX_EditField.Value;
            kappa_comR = app.kappa_comR_EditField.Value;
            delta_tf = app.delta_tf_Spinner.Value;
            delta_mtf = app.delta_mtf_EditField.Value;
            kappaX = app.kappaX_EditField.Value;
            k_SigX = app.k_SigX_EditField.Value;
            deltaSigX = app.deltaSigX_EditField.Value;
            alpha_L = app.alpha_L_EditField.Value;
            deltaR = app.deltaR_EditField.Value;
            deltaEL = app.deltaEL_EditField.Value;
            comR_tilde = app.comR_tilde_EditField.Value;
        

            XIP_input = app.XIP_inputnMSpinner.Value;


            % Compute System Response
            cb_ComputeXIPResponse(app, qD,...
                                    delta_XIP ,...
                                    k_TF_m ,...
                                    delta_tf ,...
                                    k_TF_f ,...
                                    delta_mtf ,...
                                    alpha_comR ,...
                                    kappaB ,...
                                    kappa_comR ,...
                                    k_comR ,...
                                    alpha_sigX ,...
                                    kappaX ,...
                                    k_SigX ,...
                                    deltaSigX ,...
                                    deltaR ,...
                                    alpha_L ,...
                                    deltaEL,...
                                    comR_tilde, ...
                                    XIP_input);


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Callback function
        function XIPGOButtonPushed(app, event)
            calcSysResponse(app);
        end

        % Value changed function: XIP_inputnMSpinner, alpha_L_Spinner, 
        % ...and 17 other components
        function SpinnerValueChanged(app, event)


%             tag = event.Source.Tag;
%             % Update correpsonding value field 
%             updatedValue = num2str(tag);
%             setFieldVal_cmdStr = "app."+string(tag)+"_EditField.Value="+eval(updatedValue);
%             eval(setFieldVal_cmdStr);


            % Update Display Values of EditFields
            update_EditFields(app);

            % Calculate System Response
            calcSysResponse(app);

        end

        % Value changed function: alpha_L_Slider, alpha_comR_Slider, 
        % ...and 15 other components
        function SliderValueChanged(app, event)
            
            % Log-Slider Core Function
            value = 10^round(event.Source.Value);


            % Snap Slider to rounded value
            event.Source.Value = round(event.Source.Value);
            

            tag = event.Source.Tag;

            % Update corresponding Spinner Increment/Step

            setSpinnerStep_cmdStr = "app."+string(tag)+"_Spinner.Step="+num2str(value);
            eval(setSpinnerStep_cmdStr);


            % Change Spinner Value to Slider Value
            setSpinner_cmdStr = "app."+string(tag)+"_Spinner.Value="+num2str(value);
            eval(setSpinner_cmdStr);

            update_EditFields(app);

            calcSysResponse(app);


        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {918, 918};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {535, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.LeftPanel);
            app.kappaX_Spinner = uispinner(app.GridLayout3);
            app.kappaX_Spinner.Step = 0.001;
            app.kappaX_Spinner.Limits = [1e-20 1e+20];
            app.kappaX_Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.kappaX_Spinner.Tag = 'kappaX';
            app.kappaX_Spinner.Value = 1.8066;
            app.k_comRSliderLabel_2 = uilabel(app.GridLayout3);
            app.k_comRSliderLabel_2.Text = 'kappaX';
            app.k_SigX_Spinner = uispinner(app.GridLayout3);
            app.k_SigX_Spinner.Step = 0.001;
            app.k_SigX_Spinner.Limits = [1e-20 1e+20];
            app.k_SigX_Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.k_SigX_Spinner.Tag = 'k_SigX';
            app.k_SigX_Spinner.Value = 27.09;
            app.k_comRSliderLabel_3 = uilabel(app.GridLayout3);
            app.k_comRSliderLabel_3.Text = 'k_SigX';
            app.alpha_L_Spinner = uispinner(app.GridLayout3);
            app.alpha_L_Spinner.Step = 0.001;
            app.alpha_L_Spinner.Limits = [1e-20 1e+20];
            app.alpha_L_Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.alpha_L_Spinner.Tag = 'alpha_L';
            app.alpha_L_Spinner.Value = 1;
            app.k_comRSliderLabel_6 = uilabel(app.GridLayout3);
            app.k_comRSliderLabel_6.Text = 'alpha_L';
            app.delta_XIP_EditField = uieditfield(app.GridLayout3, 'numeric');
            app.k_TF_f_EditField = uieditfield(app.GridLayout3, 'numeric');
            app.delta_mtf_EditField = uieditfield(app.GridLayout3, 'numeric');
            app.kappaB_EditField = uieditfield(app.GridLayout3, 'numeric');
            app.k_comR_EditField = uieditfield(app.GridLayout3, 'numeric');
            app.kappaX_EditField = uieditfield(app.GridLayout3, 'numeric');
            app.k_SigX_EditField = uieditfield(app.GridLayout3, 'numeric');
            app.alpha_L_EditField = uieditfield(app.GridLayout3, 'numeric');
            app.Spinner2_6Label = uilabel(app.GridLayout3);
            app.Spinner2_6Label.Text = 'delta_XIP';
            app.delta_XIP_Spinner = uispinner(app.GridLayout3);
            app.delta_XIP_Spinner.Step = 1e-05;
            app.delta_XIP_Spinner.Limits = [1e-05 100];
            app.delta_XIP_Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.delta_XIP_Spinner.Tag = 'delta_XIP';
            app.delta_XIP_Spinner.Value = 0.07;
            app.Spinner2_7Label = uilabel(app.GridLayout3);
            app.Spinner2_7Label.Text = 'k_TF_f';
            app.k_TF_f_Spinner = uispinner(app.GridLayout3);
            app.k_TF_f_Spinner.Step = 0.001;
            app.k_TF_f_Spinner.Limits = [1e-06 1000];
            app.k_TF_f_Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.k_TF_f_Spinner.Tag = 'k_TF_f';
            app.k_TF_f_Spinner.Value = 0.001;
            app.Spinner2_8Label = uilabel(app.GridLayout3);
            app.Spinner2_8Label.Text = 'delta_mtf';
            app.delta_mtf_Spinner = uispinner(app.GridLayout3);
            app.delta_mtf_Spinner.Step = 1e-05;
            app.delta_mtf_Spinner.Limits = [1e-05 100];
            app.delta_mtf_Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.delta_mtf_Spinner.Tag = 'delta_mtf';
            app.delta_mtf_Spinner.Value = 0.01;
            app.Spinner2_9Label = uilabel(app.GridLayout3);
            app.Spinner2_9Label.Text = 'kappaB';
            app.kappaB_Spinner = uispinner(app.GridLayout3);
            app.kappaB_Spinner.Step = 0.001;
            app.kappaB_Spinner.Limits = [1e-20 1e+20];
            app.kappaB_Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.kappaB_Spinner.Tag = 'kappaB';
            app.kappaB_Spinner.Value = 0.01;
            app.Spinner2_10Label = uilabel(app.GridLayout3);
            app.Spinner2_10Label.Text = 'k_comR';
            app.k_comR_Spinner = uispinner(app.GridLayout3);
            app.k_comR_Spinner.Step = 0.001;
            app.k_comR_Spinner.Limits = [1e-20 1e+20];
            app.k_comR_Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.k_comR_Spinner.Tag = 'k_comR';
            app.k_comR_Spinner.Value = 1.8066;
            app.deltaEL_Spinner = uispinner(app.GridLayout3);
            app.deltaEL_Spinner.Step = 1e-05;
            app.deltaEL_Spinner.Limits = [1e-20 1e+20];
            app.deltaEL_Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.deltaEL_Spinner.Tag = 'deltaEL';
            app.deltaEL_Spinner.Value = 0.015;
            app.k_comRSliderLabel_8 = uilabel(app.GridLayout3);
            app.k_comRSliderLabel_8.Text = 'deltaEL';
            app.deltaEL_EditField = uieditfield(app.GridLayout3, 'numeric');
            app.deltaR_Spinner = uispinner(app.GridLayout3);
            app.deltaR_Spinner.Step = 1e-05;
            app.deltaR_Spinner.Limits = [1e-20 1e+20];
            app.deltaR_Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.deltaR_Spinner.Tag = 'deltaR';
            app.deltaR_Spinner.Value = 0.00152;
            app.k_comRSliderLabel_5 = uilabel(app.GridLayout3);
            app.k_comRSliderLabel_5.Text = 'deltaR';
            app.deltaR_EditField = uieditfield(app.GridLayout3, 'numeric');
            app.deltaSigX_Spinner = uispinner(app.GridLayout3);
            app.deltaSigX_Spinner.Step = 1e-05;
            app.deltaSigX_Spinner.Limits = [1e-20 1e+20];
            app.deltaSigX_Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.deltaSigX_Spinner.Tag = 'deltaSigX';
            app.deltaSigX_Spinner.Value = 0.2;
            app.k_comRSliderLabel_4 = uilabel(app.GridLayout3);
            app.k_comRSliderLabel_4.Text = 'deltaSigX';
            app.deltaSigX_EditField = uieditfield(app.GridLayout3, 'numeric');
            app.Spinner2_3Label = uilabel(app.GridLayout3);
            app.Spinner2_3Label.Text = 'delta_tf';
            app.delta_tf_Spinner = uispinner(app.GridLayout3);
            app.delta_tf_Spinner.Step = 1e-05;
            app.delta_tf_Spinner.Limits = [1e-05 100];
            app.delta_tf_Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.delta_tf_Spinner.Tag = 'delta_tf';
            app.delta_tf_Spinner.Value = 0.01;
            app.delta_tf_EditField = uieditfield(app.GridLayout3, 'numeric');
            app.kappa_comRLabel = uilabel(app.GridLayout3);
            app.kappa_comRLabel.Text = 'kappa_comR';
            app.kappa_comR_Spinner = uispinner(app.GridLayout3);
            app.kappa_comR_Spinner.Step = 0.001;
            app.kappa_comR_Spinner.Limits = [1e-20 1e+20];
            app.kappa_comR_Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.kappa_comR_Spinner.Tag = 'kappa_comR';
            app.kappa_comR_Spinner.Value = 9.575;
            app.kappa_comR_EditField = uieditfield(app.GridLayout3, 'numeric');
            app.Spinner2_5Label = uilabel(app.GridLayout3);
            app.Spinner2_5Label.Text = 'alpha_sigX';
            app.alpha_sigX_Spinner = uispinner(app.GridLayout3);
            app.alpha_sigX_Spinner.Step = 0.001;
            app.alpha_sigX_Spinner.Limits = [0 1];
            app.alpha_sigX_Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.alpha_sigX_Spinner.Tag = 'alpha_sigX';
            app.alpha_sigX_Spinner.Value = 1;
            app.alpha_sigX_EditField = uieditfield(app.GridLayout3, 'numeric');
            app.Spinner2_4Label = uilabel(app.GridLayout3);
            app.Spinner2_4Label.Text = 'alpha_comR';
            app.alpha_comR_Spinner = uispinner(app.GridLayout3);
            app.alpha_comR_Spinner.Step = 0.0001;
            app.alpha_comR_Spinner.Limits = [0 1];
            app.alpha_comR_Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.alpha_comR_Spinner.Tag = 'alpha_comR';
            app.alpha_comR_Spinner.Value = 1;
            app.alpha_comR_EditField = uieditfield(app.GridLayout3, 'numeric');
            app.Spinner2_2Label = uilabel(app.GridLayout3);
            app.Spinner2_2Label.Text = 'k_TF_m';
            app.k_TF_m_Spinner = uispinner(app.GridLayout3);
            app.k_TF_m_Spinner.Step = 0.001;
            app.k_TF_m_Spinner.Limits = [1e-20 1e+20];
            app.k_TF_m_Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.k_TF_m_Spinner.Tag = 'k_TF_m';
            app.k_TF_m_Spinner.Value = 0.0091;
            app.k_TF_m_EditField = uieditfield(app.GridLayout3, 'numeric');
            app.Spinner2Label = uilabel(app.GridLayout3);
            app.Spinner2Label.Text = 'qD';
            app.qD_Spinner = uispinner(app.GridLayout3);
            app.qD_Spinner.Step = 1e-05;
            app.qD_Spinner.Limits = [1e-20 1e+20];
            app.qD_Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.qD_Spinner.Tag = 'qD';
            app.qD_Spinner.Value = 1;
            app.qD_EditField = uieditfield(app.GridLayout3, 'numeric');
            app.qD_Slider = uislider(app.GridLayout3);
            app.qD_Slider.Limits = [-10 10];
            app.qD_Slider.MajorTicks = [-10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10];
            app.qD_Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.qD_Slider.MinorTicks = [];
            app.qD_Slider.Tag = 'qD';
            app.delta_XIP_Slider = uislider(app.GridLayout3);
            app.delta_XIP_Slider.Limits = [-10 10];
            app.delta_XIP_Slider.MajorTicks = [-10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10];
            app.delta_XIP_Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.delta_XIP_Slider.MinorTicks = [];
            app.delta_XIP_Slider.Tag = 'delta_XIP';
            app.k_TF_m_Slider = uislider(app.GridLayout3);
            app.k_TF_m_Slider.Limits = [-10 10];
            app.k_TF_m_Slider.MajorTicks = [-10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10];
            app.k_TF_m_Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.k_TF_m_Slider.MinorTicks = [];
            app.k_TF_m_Slider.Tag = 'k_TF_m';
            app.k_TF_f_Slider = uislider(app.GridLayout3);
            app.k_TF_f_Slider.Limits = [-10 10];
            app.k_TF_f_Slider.MajorTicks = [-10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10];
            app.k_TF_f_Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.k_TF_f_Slider.MinorTicks = [];
            app.k_TF_f_Slider.Tag = 'k_TF_f';
            app.delta_tf_Slider = uislider(app.GridLayout3);
            app.delta_tf_Slider.Limits = [-10 10];
            app.delta_tf_Slider.MajorTicks = [-10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10];
            app.delta_tf_Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.delta_tf_Slider.MinorTicks = [];
            app.delta_tf_Slider.Tag = 'delta_tf';
            app.delta_mtf_Slider = uislider(app.GridLayout3);
            app.delta_mtf_Slider.Limits = [-10 10];
            app.delta_mtf_Slider.MajorTicks = [-10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10];
            app.delta_mtf_Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.delta_mtf_Slider.MinorTicks = [];
            app.delta_mtf_Slider.Tag = 'delta_mtf';
            app.alpha_comR_Slider = uislider(app.GridLayout3);
            app.alpha_comR_Slider.Limits = [-10 10];
            app.alpha_comR_Slider.MajorTicks = [-10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10];
            app.alpha_comR_Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.alpha_comR_Slider.MinorTicks = [];
            app.alpha_comR_Slider.Tag = 'alpha_comR';
            app.kappaB_Slider = uislider(app.GridLayout3);
            app.kappaB_Slider.Limits = [-10 10];
            app.kappaB_Slider.MajorTicks = [-10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10];
            app.kappaB_Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.kappaB_Slider.MinorTicks = [];
            app.kappaB_Slider.Tag = 'kappaB';
            app.kappa_comR_Slider = uislider(app.GridLayout3);
            app.kappa_comR_Slider.Limits = [-10 10];
            app.kappa_comR_Slider.MajorTicks = [-10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10];
            app.kappa_comR_Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.kappa_comR_Slider.MinorTicks = [];
            app.kappa_comR_Slider.Tag = 'kappa_comR';
            app.k_comR_Slider = uislider(app.GridLayout3);
            app.k_comR_Slider.Limits = [-10 10];
            app.k_comR_Slider.MajorTicks = [-10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10];
            app.k_comR_Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.k_comR_Slider.MinorTicks = [];
            app.k_comR_Slider.Tag = 'k_comR';
            app.alpha_sigX_Slider = uislider(app.GridLayout3);
            app.alpha_sigX_Slider.Limits = [-10 10];
            app.alpha_sigX_Slider.MajorTicks = [-10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10];
            app.alpha_sigX_Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.alpha_sigX_Slider.MinorTicks = [];
            app.alpha_sigX_Slider.Tag = 'alpha_sigX';
            app.kappaX_Slider = uislider(app.GridLayout3);
            app.kappaX_Slider.Limits = [-10 10];
            app.kappaX_Slider.MajorTicks = [-10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10];
            app.kappaX_Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.kappaX_Slider.MinorTicks = [];
            app.kappaX_Slider.Tag = 'kappaX';
            app.deltaSigX_Slider = uislider(app.GridLayout3);
            app.deltaSigX_Slider.Limits = [-10 10];
            app.deltaSigX_Slider.MajorTicks = [-10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10];
            app.deltaSigX_Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.deltaSigX_Slider.MinorTicks = [];
            app.deltaSigX_Slider.Tag = 'deltaSigX';
            app.k_SigX_Slider = uislider(app.GridLayout3);
            app.k_SigX_Slider.Limits = [-10 10];
            app.k_SigX_Slider.MajorTicks = [-10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10];
            app.k_SigX_Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.k_SigX_Slider.MinorTicks = [];
            app.k_SigX_Slider.Tag = 'k_SigX';
            app.deltaR_Slider = uislider(app.GridLayout3);
            app.deltaR_Slider.Limits = [-10 10];
            app.deltaR_Slider.MajorTicks = [-10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10];
            app.deltaR_Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.deltaR_Slider.MinorTicks = [];
            app.deltaR_Slider.Tag = 'deltaR';
            app.deltaEL_Slider = uislider(app.GridLayout3);
            app.deltaEL_Slider.Limits = [-10 10];
            app.deltaEL_Slider.MajorTicks = [-10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10];
            app.deltaEL_Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.deltaEL_Slider.MinorTicks = [];
            app.deltaEL_Slider.Tag = 'deltaEL';
            app.alpha_L_Slider = uislider(app.GridLayout3);
            app.alpha_L_Slider.Limits = [-10 10];
            app.alpha_L_Slider.MajorTicks = [-10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10];
            app.alpha_L_Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.alpha_L_Slider.MinorTicks = [];
            app.alpha_L_Slider.Tag = 'alpha_L';
            app.comR_tilde_Spinner = uispinner(app.GridLayout3);
            app.comR_tilde_Spinner.Step = 100000;
            app.comR_tilde_Spinner.Limits = [1e-20 1e+20];
            app.comR_tilde_Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.comR_tilde_Spinner.Tag = 'alpha_L';
            app.comR_tilde_Spinner.Value = 9756100;
            app.comR_tilde_EditField = uieditfield(app.GridLayout3, 'numeric');
            app.comR_tilde_SliderLabel = uilabel(app.GridLayout3);
            app.comR_tilde_SliderLabel.Text = 'comR_tilde';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.XIP_intUIAxes = uiaxes(app.GridLayout2);
            title(app.XIP_intUIAxes, 'XIP_{int}')
            xlabel(app.XIP_intUIAxes, 'Time')
            ylabel(app.XIP_intUIAxes, 'Y')
            zlabel(app.XIP_intUIAxes, 'Z')
            app.XIP_extUIAxes = uiaxes(app.GridLayout2);
            title(app.XIP_extUIAxes, 'XIP_ {ext}')
            xlabel(app.XIP_extUIAxes, 'Time')
            ylabel(app.XIP_extUIAxes, 'Concentration')
            zlabel(app.XIP_extUIAxes, 'Z')
            app.comRUIAxes = uiaxes(app.GridLayout2);
            title(app.comRUIAxes, 'comR')
            xlabel(app.comRUIAxes, 'Time')
            ylabel(app.comRUIAxes, 'Amount')
            zlabel(app.comRUIAxes, 'Z')
            app.comR_XIPUIAxes = uiaxes(app.GridLayout2);
            title(app.comR_XIPUIAxes, 'comR\\XIP-Complex')
            xlabel(app.comR_XIPUIAxes, 'Time')
            ylabel(app.comR_XIPUIAxes, 'Amount')
            zlabel(app.comR_XIPUIAxes, 'Z')
            app.mRNAUIAxes = uiaxes(app.GridLayout2);
            title(app.mRNAUIAxes, 'mRNA')
            xlabel(app.mRNAUIAxes, 'Time')
            ylabel(app.mRNAUIAxes, 'Amount')
            zlabel(app.mRNAUIAxes, 'Z')
            app.LuciferaseUIAxes = uiaxes(app.GridLayout2);
            title(app.LuciferaseUIAxes, 'Luciferase')
            xlabel(app.LuciferaseUIAxes, 'Time')
            ylabel(app.LuciferaseUIAxes, 'Amount')
            zlabel(app.LuciferaseUIAxes, 'Z')
            app.SigXUIAxes = uiaxes(app.GridLayout2);
            title(app.SigXUIAxes, 'SigX')
            xlabel(app.SigXUIAxes, 'Time')
            ylabel(app.SigXUIAxes, 'Amount')
            zlabel(app.SigXUIAxes, 'Z')
            app.XIP_inputnMSpinnerLabel = uilabel(app.GridLayout2);
            app.XIP_inputnMSpinnerLabel.Text = 'XIP_input (nM)';
            app.XIP_inputnMSpinner = uispinner(app.GridLayout2);
            app.XIP_inputnMSpinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.XIP_inputnMSpinner.Value = 62.5;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TestApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end